classdef project_ppt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        NghiemTab                    matlab.ui.container.Tab
        EndEditField                 matlab.ui.control.NumericEditField
        EndEditFieldLabel            matlab.ui.control.Label
        StartEditField               matlab.ui.control.NumericEditField
        StartEditFieldLabel          matlab.ui.control.Label
        nEditField                   matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel          matlab.ui.control.Label
        resultEditField              matlab.ui.control.NumericEditField
        KtquEditFieldLabel           matlab.ui.control.Label
        ppListBox                    matlab.ui.control.ListBox
        PhngphpListBoxLabel          matlab.ui.control.Label
        errorEditField               matlab.ui.control.NumericEditField
        NhpsaischophpEditFieldLabel  matlab.ui.control.Label
        NhpkhongphnlynghimABLabel    matlab.ui.control.Label
        BEditField                   matlab.ui.control.NumericEditField
        BEditFieldLabel              matlab.ui.control.Label
        AEditField                   matlab.ui.control.NumericEditField
        AEditFieldLabel              matlab.ui.control.Label
        inputEditField               matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel    matlab.ui.control.Label
        RUNButton                    matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        He_phuong_trinh_Tab          matlab.ui.container.Tab
        Ma_tran_ket_qua              matlab.ui.control.EditField
        AEditField_2Label_4          matlab.ui.control.Label
        Ma_tran_b                    matlab.ui.control.EditField
        AEditField_2Label_3          matlab.ui.control.Label
        Ma_tran_A                    matlab.ui.control.EditField
        AEditField_2Label_2          matlab.ui.control.Label
        RUNButton_he_phuong_trinh    matlab.ui.control.Button
        qua_trinh_giai_he            matlab.ui.control.TextArea
        QutrnhthchinTextAreaLabel    matlab.ui.control.Label
        method_drop_down             matlab.ui.control.DropDown
        PhngphpDropDownLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        flag = 0; % Description
        c = -2;
    end
    
    methods (Access = private)
        
        %% Hàm tìm nghiệm
        function [x,n] = chiadoi(app, fx, a, b, error)
            % Kiểm tra hàm số liên tục trong khoảng phân ly không
            if fx(a)*fx(b) >= 0
                uialert(app.UIFigure,['Hàm số không liên tục trong khoảng phân ly [a, b]. ' ...
                    'Vui lòng nhập lại [a, b] hoặc hàm số khác!'], 'lỗi');
                x = NaN; n = 0; return;
            end

            n = 1; % Số lần lặp
            max_in = 1000;
            while n < max_in
                x = (a + b)/2;

                if fx(x)*fx(b) > 0
                    b = x;
                else 
                    a = x;
                end

                if abs(b - a) < error
                return;
                end
            n = n + 1;
            end
            uialert(app.UIFigure, 'Đã hết số lần lặp tối đa mà chưa đạt sai số mong muốn.', 'Cảnh báo');
            end
        
        function [x, n] = lap(app, g, a, b, error)
            % Kiểm tra đạo hàm của g có q < 1 trong đoạn [a, b]
            syms x;
            g_sym = str2sym(g);
            dg_sym = diff(g_sym,x);
            dG = matlabFunction(dg_sym);disp(dG)
            if double(dG(a)) > 1 || double(dG(b)) > 1
                uialert(app.UIFigure, ['Đạo hàm g(x) = q > 1, không hội tụ! Vui lòng nhập lại hàm g(x) ' ...
                    'hoặc khoảng phân ly [a, b] khác'], 'Lỗi');
                x = NaN; n = 0; return;
            end
            
            g = str2func(['@(x)', g]);
            n = 1;
            %x0 = (a+b)/2;
            x0 = a;
            max_in = 1000;
            while n < max_in
                x = g(x0);

                % Kiểm tra sai số
                if abs(x - x0) < error
                    return;
                end
                
            n = n + 1;
            x0 = x;
            end
            x = x0; % Nếu vượt quá x bằng giá trị của số lần lặp tối đa
            uialert(app.UIFigure, 'Đã hết số lần lặp tối đa mà chưa đạt sai số mong muốn.', 'Cảnh báo');
        end
        
        function [x,n] = newton_raphson(app, fx, a, b, error)
            % Tính đạo hàm
            try
            syms x;
            f_sym = str2sym(fx);

            df_sym = diff(f_sym, x);
            ddf_sym = diff(df_sym, x);

            % Chuyển về hàm số
            F = matlabFunction(f_sym);%disp(F);
            dF = matlabFunction(df_sym);%disp(dF);
            ddF = matlabFunction(ddf_sym);%disp(ddF);
            catch
                uialert(app.UIFigure, 'Lỗi cú pháp phương trình!', 'Lỗi');
                x = NaN; n = 0; return;
            end
            
            % Xét điều kiện hội tụ
            if double(dF(a))*double(dF(b)) < 0
                uialert(app.UIFigure, 'Đạo hàm cấp 1 của hàm số đổi dấu trên khoảng [a, b]. Không hội tụ.', 'Không thỏa điều kiện');
                x = NaN; n = 0; return;
            elseif double(ddF(a))*double(ddF(b)) < 0
                uialert(app.UIFigure, 'Đạo hàm cấp 2 của hàm số đổi dấu trên khoảng [a, b]. Không hội tụ.', 'Không thỏa điều kiện');
                x = NaN; n = 0; return;
            end
            
            if double(F(a))*double(ddF(a)) > 0
                x0 = a;
            else 
                x0 = b;
            end

            %x0 = (a + b)/2;
            n = 0;
            max_in = 1000;
            while n < max_in
                n = n + 1;
                x1 = x0 - double(F(x0))/double(dF(x0));
                if abs(x1 - x0) < error
                    x = x1;
                return;
                end
                x0 = x1;
            end
        x = x1;  % Nếu vượt quá số lần lặp tối đa
        uialert(app.UIFigure, 'Đã hết số lần lặp tối đa mà chưa đạt sai số mong muốn.', 'Cảnh báo');
        end
        
        %% Hàm giải hệ phương trình
        function [X, L, U, Y] = LU_analysis(app, A, B)
            %disp(A);
            [n, m] = size(A);

            % --- Ma trận A phải là ma trận vuông ---
            if n ~= m
                uialert(app.UIFigure, 'Ma trận A phải là ma trận vuông!', 'Lỗi kích thước');
                error('Input matrix A must be square!'); %Dừng hàm
            end

            % --- Kiểm tra B có khớp với A không ---
            [nB, mB] = size(B);
            % Đảm bảo B là vector cột
            if mB ~= 1
                % Nếu lỡ nhập hàng ngang thì chuyển vị luôn
                if nB == 1 && mB == m
                    B = B';
                    nB = mB;
                else
                    uialert(app.UIFigure, 'Vector B phải có kích thước N x 1!', 'Lỗi kích thước');
                    error("Vector B dimension mismatch!");
                end
            end

            if nB ~= n
                uialert(app.UIFigure, 'Số hàng của A và B không khớp nhau!', 'Lỗi kích thước');
                error("Dimension mismatch between A and B!");
            end

            % ----- Thuật toán -----
            L = eye(n); 
            U = zeros(n,n);
    
            % B1: Phân tích A = L.U
            U(1,:) = A(1,:); % Khởi tạo hàng đầu của U

            % Kiểm tra phần tử đầu tiên có bằng 0 không
            if abs(U(1,1)) < 1e-12
                uialert(app.UIFigure, 'Phần tử U(1,1) bằng 0. Không thể chia!', 'Lỗi chia 0');
                error('Division by zero detected');
            end
    
            for k = 2:n
                for l = 1:k-1
                    if l == 1
                        L(k,l) = A(k,l)/U(l,l);
                    else
                        temp = sum(L(k,1:l-1)*U(1:l-1,l));
                        L(k,l) = (A(k,l) - temp)/U(l,l);
                    end
                end

                for f = k:m
                    temp = sum(L(k,1:k-1)*U(1:k-1,f));
                    U(k,f) = A(k,f) - temp;
                end
            end
            %disp(L); disp(U);

            % Check phần từ cuối cùng của U trước khi phải ngược
            if abs(U(n,n)) < 1e-12
                uialert(app.UIFigure, 'Ma trận suy biến. Không có nghiệm duy nhất!.', 'Lỗi toán học');
                X=[]; L=[]; U=[]; Y=[]; return;
            end

            % B2: L.Y = B -> Tìm Y
            Y = zeros(n,1);
            Y(1,1) = B(1,1)/L(1,1);
            for k = 2:n
                Y(k,1) = (B(k,1) - sum(L(k,1:k-1)*Y(1:k-1,1)))/L(k,k);
            end
            %disp(Y);

            % B2: U.X = Y
            X = zeros(n,1);
            X(n,1) = Y(n,1)/U(n,n);
            for k = n-1:-1:1
                X(k,1) = (Y(k,1) - sum(U(k,n:-1:k+1)*X(n:-1:k+1,1)))/U(k,k);
            end
            %disp(X);
        end

        function [X, process_log] = Gauss(app, A, b)
            
            [nA, mA] = size(A);
            % --- Ma trận A phải là ma trận vuông ---
            if nA ~= mA
                uialert(app.UIFigure, 'Ma trận A phải là ma trận vuông!', 'Lỗi kích thước');
                error('Input matrix A must be square!'); %Dừng hàm
            end

            % Đảm bảo b là cột
            if size(b, 2) > 1
                b = b';
            end

            if length(b) ~= mA
                uialert(app.UIFigure, ['Vector b phải có ' num2str(mA) ' phần tử'], 'lỗi kích thước');
                X=[]; process_log=""; return;
            end

            % ----- Thuật toán -----
            Ab = [A, b];
            [m, n] = size(Ab);

            % Khởi tạo log
            process_log = "--- Ma trận Ab ---" + newline;
            process_log = process_log + join(string(num2str(Ab, '%.4f    ')), newline) + newline + newline;
            % ----- Quá trình thuận -----
            for k = 1:m
                % Kiểm tra phần trụ bằng 0
                pivot = Ab(k,k);
                if abs(pivot) < 1e-12
                    uialert(app.UIFigure, ['Phần tử chia tại dòng '  num2str(k) 'bằng 0. Không thể giải tiếp!'], 'Lỗi tính toán');
                    X=[]; return;
                end

                Ab(k,:) = Ab(k,:) ./ Ab(k,k); % Chuẩn hóa
                for l = (k+1):m
                    Ab(l,:) = Ab(l,:) - Ab(l,k).*Ab(k,:);% Khử x
                end
                %disp(Ab);

                process_log = process_log + "--- Chuẩn hóa cột " + k + " và khử cột " + k + " ---" + newline;
                process_log = process_log + join(string(num2str(Ab, '%.4f    ')), newline) + newline + newline;
            end

            % ----- Quá trình nghịch -----
            X = zeros(m,1);
            X(m,1) = Ab(m,n)./Ab(m,m);
            for k = (m-1):-1:1
                X(k) = (Ab(k,n) - Ab(k,k+1:m)*X(k+1:m)) / Ab(k,k);
            end
            %disp(X);
            process_log = process_log + "--- Kết quả nghiệm x ---" + newline;
            process_log = process_log + join(string(num2str(X, '%.4f ')), newline);
        end

        function [X, process_log] = GaussJordan(app, A, b)
            [nA, mA] = size(A);
            % Ma trận A phải là ma trận vuông
            if(nA ~= mA)
                uiAlert(app.UIFigure,"Ma trận A không phải ma trận vuông", "Lỗi kích thước");
                error("Input matrix A must be square!");
            end

            % Đảm bảo b là cột
            if size(b,2) > 1
                b = b';
            end

            if length(b) ~= mA
                uialert(app.UIFigure, ['Vector b phải có ' num2str(mA) ' phần tử'], 'Lỗi kích thước');
                X = []; process_log = "";return;
            end

            % Thuật toán
            Ab = [A b];
            [m, n] = size(Ab);
            % Quá trình thuận
            process_log = "--- Ma trận Ab ---" + newline;
            process_log = process_log + join(string(num2str([A b], '%.4f ')), newline) + newline + newline;
            for k = 1:m
                % Kiểm tra phần trụ bằng 0
                pivot = Ab(k,k);
                if abs(pivot) < 1e-12
                    uialert(app.UIFigure, ['Phần tử chia tại dòng '  num2str(k) 'bằng 0. Không thể giải tiếp!'], 'Lỗi tính toán');
                    X=[]; return;
                end
                Ab(k,:) = Ab(k,:) ./ Ab(k,k);
                for l = 1:m
                    if l ~= k
                        Ab(l, :) = Ab(l,:) - Ab(l,k) .* Ab(k,:);
                    end
                end
                process_log = process_log + "--- Chuẩn hóa hàng " + num2str(k) + " khử cột " + num2str(k) + ' ---' + newline;
                process_log = process_log + join(string(num2str(Ab, '%.4f    ')), newline) + newline + newline; 
            end

            % Quá trình nghịch
            X = size(m,1);
            X(m,1) = Ab(m,n)/Ab(m,n);
            for k = (m-1):-1:1
                X(k) = (Ab(k,n) - Ab(k,k+1:m)*X(k+1:m))/Ab(k,k);
            end
            process_log = process_log + "--- Kết quả nghiệm x ---" + newline;
            process_log = process_log + join(string(num2str(X, '%.4f ')), newline);
        end
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Tab tìm nghiệm
            % Các thông số khởi tạo ban đầu 
            app.AEditField.Value = 0;
            app.BEditField.Value = 0.5;
            app.EndEditField.Value = 0.5;
            app.StartEditField.Value = -0.2;
            app.errorEditField.Value = 0.01;

            % Mặc định cho listbox
            app.ppListBox.Value = 'Chia đôi';
            app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)

            % QUAN TRỌNG: Kích hoạt nút RUN tự động
            % Gọi hàm callback của nút RUN như một hàm bình thường
            % Tham số thứ 2 là event, truyền [] (rỗng) vì không click chuột thật
            RUNButtonPushed(app, []);

            %% Tab giải hệ phương trình
            app.Ma_tran_A.Value = '[10+30,-10,-30;-10,10+15+5,-5;-30,-5,5+30+30]';
            app.Ma_tran_b.Value = '[10; 0; 0]';

            app.method_drop_down.Value = 'LU';
            RUNButton_he_phuong_trinhPushed(app, []); % Khởi tạo chạy cho app hệ phương trình
        end

        % Callback function: EndEditField, RUNButton, StartEditField, 
        % ...and 1 other component
        function RUNButtonPushed(app, event)
            disp("--------- DEBUG: Nhấn nút được! ---------");
            % ----- Dữ liệu đầu vào -----
            endp = app.EndEditField.Value;
            startp = app.StartEditField.Value;
            func_str = app.inputEditField.Value;     

            % VD: x^2 -> x.^2
            draw_str = strrep(func_str, '^', '.^');
            draw_str = strrep(draw_str, '*', '.*');
            draw_str = strrep(draw_str, '/', './');
            func = str2func(['@(x)', draw_str]);
            disp(func);
            % ----- Thông số - tính toán vẽ đồ thị ------
            fs = 10000;
            t = linspace(startp, endp, fs);
            y = func(t);

            % ------ Vẽ đồ thị hàm số ------
            plot(app.UIAxes, real(t), real(y), 'b','LineWidth', 1.6);
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'on');

            % ------ Tìm nghiệm xấp xỉ ------
            a = app.AEditField.Value;
            b = app.BEditField.Value;
            error = app.errorEditField.Value;
            
            func_calc = str2func(['@(x)', func_str]);
            selectedOption = app.ppListBox.Value;
            try
                switch selectedOption
                    case 'Chia đôi'
                        [x, n] = app.chiadoi(func_calc, a, b, error);
                        yline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        xline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        % Vẽ điểm nghiệm
                        plot(app.UIAxes, x, func_calc(x), 'ro', 'MarkerFaceColor', 'r');
                        hold(app.UIAxes, 'off');
                    case 'Lặp'
                        [x, n] = app.lap(func_str, a, b, error);
                        yLine = @(k)(k);
                        plot(app.UIAxes, t, yLine(t), 'g-', 'LineWidth', 1.0);
                        xline(app.UIAxes, x, 'k--', 'LineWidth', 1.0);
                        % Vẽ điểm nghiệm
                        plot(app.UIAxes, real(x), 0, 'ro', 'MarkerFaceColor', 'r');
                        yline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        xline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        hold(app.UIAxes, 'off');
                    case 'Newton (tiếp tuyến)'
                        [x, n] = app.newton_raphson(func_str, a, b, error);
                        yline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        xline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        % Vẽ điểm nghiệm
                        plot(app.UIAxes, x, func_calc(x), 'ro', 'MarkerFaceColor', 'r');
                        hold(app.UIAxes, 'off');
                end
                
                % Hiển thị kết quả
                app.nEditField.Value = n;
                app.resultEditField.Value = double(x);
               
            catch ME
                %uialert(app.UIFigure, ME.message, 'Lỗi');%Debug
            end
            
        end

        % Value changed function: inputEditField
        function inputEditFieldValueChanged(app, event)
            currentStr = app.inputEditField.Value;
            if isempty(currentStr)
                app.flag = 0;

                currentMethod = app.ppListBox.Value;
                switch currentMethod
                    case 'Chia đôi'        
                        app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)
                    case 'Lặp'
                        app.inputEditField.Value = '1 - sqrt(2)/2*exp(1/2*x)'; % g(x)
                    case 'Newton (tiếp tuyến)'
                        app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)
                end
            else
                app.flag = 1;
            end
        end

        % Callback function
        function StartEditFieldValueChanged(app, event)
            
            
        end

        % Callback function
        function EndEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: ppListBox
        function ppListBoxValueChanged(app, event)
            selectedOption = app.ppListBox.Value;

            if app.flag == 0
                switch selectedOption
                    case 'Chia đôi'        
                        app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)
                    case 'Lặp'
                        app.inputEditField.Value = '1 - sqrt(2)/2*exp(1/2*x)'; % g(x)
                    case 'Newton (tiếp tuyến)'
                        app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)
                end
            else 
                return;
            end
        end

        % Value changed function: AEditField
        function AEditFieldValueChanged(app, event)
            app.StartEditField.Value = app.AEditField.Value + app.c;
        end

        % Value changed function: BEditField
        function BEditFieldValueChanged(app, event)
            if app.BEditField.Value > 0
                app.c = 2;
            end
            app.EndEditField.Value = app.BEditField.Value + app.c;
        end

        % Callback function
        function errorEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: resultEditField
        function resultEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            
            
        end

        % Callback function
        function RUNButtonPushed2(app, event)
            
        end

        % Value changed function: method_drop_down
        function method_drop_downValueChanged(app, event)
            %value = app.method_drop_down.Value;
            
        end

        % Button pushed function: RUNButton_he_phuong_trinh
        function RUNButton_he_phuong_trinhPushed(app, event)
            strArray_A = app.Ma_tran_A.Value;
            strArray_b = app.Ma_tran_b.Value;

            numArray_A = str2num(strArray_A);
            numArray_b = str2num(strArray_b);

            % Kiểm tra người dùng có nhập đúng không
            if isempty(numArray_A) || isempty(numArray_b)
                uialert(app.UIFigure, 'Vui lòng nhập đúng định dạng mảng số!', 'Lỗi nhập liệu');
                return;
            end
            
            try
                optionDropDown_hpt = app.method_drop_down.Value;

                switch optionDropDown_hpt
                    case "Gauss"
                        [rHe_pt, process_log] = app.Gauss(numArray_A, numArray_b);

                        % Log quá trình tính toán 
                        app.qua_trinh_giai_he.Value = process_log;

                        % In ra ô kết quả
                        app.Ma_tran_ket_qua.Value = num2str(rHe_pt', '%.4f  ');

                    case "Gauss - Jordan"
                        [rHe_pt, process_log] = app.GaussJordan(numArray_A, numArray_b);
                        app.qua_trinh_giai_he.Value = process_log;
                        app.Ma_tran_ket_qua.Value = num2str(rHe_pt', '%.4f  ');
                    case "Gauss - Trụ tối đại"
                    case "LU"
                        [rHe_pt, L, U, Y] = app.LU_analysis(numArray_A, numArray_b);

                        % Log quá trình tính toán ra Text Area
                        log_text = "";
                        log_text = log_text + "--- Ma trận A ---" + newline;
                        log_text = log_text + join(string(num2str(numArray_A)), newline) + newline + newline;
                        log_text = log_text + "--- Ma trận L ---" + newline;
                        log_text = log_text + join(string(num2str(L, '%.4f   ')), newline) + newline + newline;
                        log_text = log_text + "--- Ma trận U ---" + newline;
                        log_text = log_text + join(string(num2str(U, '%.4f   ')), newline) + newline + newline;
                        log_text = log_text + "--- Vector trung gian Y ---" + newline;
                        log_text = log_text + join(string(num2str(Y, '%.4f   ')), newline) + newline + newline;
                        log_text = log_text + "--- Kết quả (Vector x) ---" + newline;
                        log_text = log_text + join(string(num2str(rHe_pt, '%.4f   ')), newline) + newline + newline;
                        app.qua_trinh_giai_he.Value = log_text;

                        % In ra ô kết quả
                        app.Ma_tran_ket_qua.Value = num2str(rHe_pt', '%.4f  ');

                    case "Cholesky"
                    case "Jacobi"
                    case "Gauss - Seidel"
                end
            catch me
                uialert(app.UIFigure, ['Lỗi tính toán: ' me.message], 'Lỗi');
            end
        end

        % Value changed function: Ma_tran_ket_qua
        function Ma_tran_ket_quaValueChanged(app, event)
            %value = app.Ma_tran_ket_qua.Value;
            
        end

        % Value changed function: qua_trinh_giai_he
        function qua_trinh_giai_heValueChanged(app, event)
            %value = app.qua_trinh_giai_he.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghiemTab = uitab(app.TabGroup);
            app.NghiemTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghiemTab);
            title(app.UIAxes, 'Đồ thị hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'f(x)')
            zlabel(app.UIAxes, 'Z')
            app.RUNButton = uibutton(app.NghiemTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.inputEditField = uieditfield(app.NghiemTab, 'text');
            app.inputEditField.ValueChangedFcn = createCallbackFcn(app, @inputEditFieldValueChanged, true);
            app.AEditFieldLabel = uilabel(app.NghiemTab);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.NghiemTab, 'numeric');
            app.AEditField.ValueChangedFcn = createCallbackFcn(app, @AEditFieldValueChanged, true);
            app.BEditFieldLabel = uilabel(app.NghiemTab);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.NghiemTab, 'numeric');
            app.BEditField.ValueChangedFcn = createCallbackFcn(app, @BEditFieldValueChanged, true);
            app.NhpkhongphnlynghimABLabel = uilabel(app.NghiemTab);
            app.NhpkhongphnlynghimABLabel.Text = 'Nhập khoảng phân ly nghiệm [A, B]';
            app.NhpsaischophpEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpsaischophpEditFieldLabel.Text = 'Nhập sai số cho phép';
            app.errorEditField = uieditfield(app.NghiemTab, 'numeric');
            app.errorEditField.ValueChangedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.PhngphpListBoxLabel = uilabel(app.NghiemTab);
            app.PhngphpListBoxLabel.Text = 'Phương pháp';
            app.ppListBox = uilistbox(app.NghiemTab);
            app.ppListBox.Items = {'Chia đôi', 'Lặp', 'Newton (tiếp tuyến)'};
            app.ppListBox.ValueChangedFcn = createCallbackFcn(app, @ppListBoxValueChanged, true);
            app.ppListBox.Value = 'Chia đôi';
            app.KtquEditFieldLabel = uilabel(app.NghiemTab);
            app.KtquEditFieldLabel.Text = 'Kêt quả';
            app.resultEditField = uieditfield(app.NghiemTab, 'numeric');
            app.resultEditField.ValueChangedFcn = createCallbackFcn(app, @resultEditFieldValueChanged, true);
            app.SlnlpEditFieldLabel = uilabel(app.NghiemTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.nEditField = uieditfield(app.NghiemTab, 'numeric');
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.StartEditFieldLabel = uilabel(app.NghiemTab);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.NghiemTab, 'numeric');
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.EndEditFieldLabel = uilabel(app.NghiemTab);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.NghiemTab, 'numeric');
            app.EndEditField.ValueChangedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.He_phuong_trinh_Tab = uitab(app.TabGroup);
            app.He_phuong_trinh_Tab.Title = 'Giải hệ phương trình';
            app.PhngphpDropDownLabel = uilabel(app.He_phuong_trinh_Tab);
            app.PhngphpDropDownLabel.Text = 'Phương pháp';
            app.method_drop_down = uidropdown(app.He_phuong_trinh_Tab);
            app.method_drop_down.Items = {'Gauss', 'Gauss - Jordan', 'Gauss - trụ tối đại', 'LU', 'Cholesky', 'Jacobi', 'Gauss - Seidel'};
            app.method_drop_down.ValueChangedFcn = createCallbackFcn(app, @method_drop_downValueChanged, true);
            app.method_drop_down.Value = 'Gauss';
            app.QutrnhthchinTextAreaLabel = uilabel(app.He_phuong_trinh_Tab);
            app.QutrnhthchinTextAreaLabel.Text = 'Quá trình thưc hiện';
            app.qua_trinh_giai_he = uitextarea(app.He_phuong_trinh_Tab);
            app.qua_trinh_giai_he.ValueChangedFcn = createCallbackFcn(app, @qua_trinh_giai_heValueChanged, true);
            app.RUNButton_he_phuong_trinh = uibutton(app.He_phuong_trinh_Tab, 'push');
            app.RUNButton_he_phuong_trinh.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_he_phuong_trinhPushed, true);
            app.RUNButton_he_phuong_trinh.Text = 'RUN';
            app.AEditField_2Label_2 = uilabel(app.He_phuong_trinh_Tab);
            app.AEditField_2Label_2.Text = 'A';
            app.Ma_tran_A = uieditfield(app.He_phuong_trinh_Tab, 'text');
            app.AEditField_2Label_3 = uilabel(app.He_phuong_trinh_Tab);
            app.AEditField_2Label_3.Text = 'b';
            app.Ma_tran_b = uieditfield(app.He_phuong_trinh_Tab, 'text');
            app.AEditField_2Label_4 = uilabel(app.He_phuong_trinh_Tab);
            app.AEditField_2Label_4.Text = 'Kết quả';
            app.Ma_tran_ket_qua = uieditfield(app.He_phuong_trinh_Tab, 'text');
            app.Ma_tran_ket_qua.ValueChangedFcn = createCallbackFcn(app, @Ma_tran_ket_quaValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project_ppt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end